classdef SegmentationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        AnalyzeButton   matlab.ui.control.Button
        WaterButton     matlab.ui.control.Button
        DesertButton    matlab.ui.control.Button
        MountainButton  matlab.ui.control.Button
        ForestButton    matlab.ui.control.Button
        CityButton      matlab.ui.control.Button
        LoadButton      matlab.ui.control.Button
        ImageAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Counter = 0
        RoiList
        ImageName
        ImageFolder
        Image
        ImageNoClasses
        ResizeFactor = 6
        f_ActiveImage = false
    end
    
    methods (Access = private)
        
        function updateImage(app,imageName,imageFolder)
            try
                imagePath = [imageFolder,imageName];
                app.Image = imread(imagePath);
                app.ImageName = imageName;
                app.ImageFolder = imageFolder;
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure,ME.message,'Image error');
                return
            end

            % Display image on axes
            resizedImage = imresize(app.Image,1/app.ResizeFactor);
            imagesc(app.ImageAxes,resizedImage)

            % Turn f_ActiveImage flag to true
            app.f_ActiveImage = true;
        end

        function drawROI(app,terrainClass)
            if (app.Counter == 0)
                app.RoiList.FreehandObj = drawfreehand(app.ImageAxes);
                app.RoiList.Class = terrainClass;
            else
                roiList.FreehandObj = drawfreehand(app.ImageAxes);
                roiList.Class = terrainClass;
                app.RoiList = [app.RoiList;roiList];
            end
            app.Counter = app.Counter+1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.jpeg;*.png;*.tif;*.tiff;'};
            [app.ImageName,app.ImageFolder] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(app.ImageFolder))
                updateImage(app,app.ImageName,app.ImageFolder);
            end

            app.RoiList = struct();
            app.Counter = 0;
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if (app.f_ActiveImage)

                % Reset ImageNoClasses
                app.ImageNoClasses = app.Image;
                
                % Image and sub-image properties
                [height,width,~] = size(app.Image);
                subImageSide = 128;
                terrainClasses = ["city","forest","mountain","desert","water"];
                
                % Create masks from ROIs
                roiList = app.RoiList;
                roiClasses = [app.RoiList.Class];
                masks = zeros(round(height/app.ResizeFactor),round(width/app.ResizeFactor),length(roiList),"logical");
                for i = 1:length(roiList)
                    masks(:,:,i) = createMask(roiList(i).FreehandObj);
                end
                masks = imresize(masks,app.ResizeFactor);
                
                % Loop through sub-images and assign a class to each one of
                % them
                numVSubImages = floor(height/subImageSide)-1;
                numHSubImages = floor(width/subImageSide)-1;
                classMap = zeros(numVSubImages,numHSubImages,"uint8");

                for m = 1:numVSubImages
                    xidx = ((m-1)*subImageSide+1):(m*subImageSide);
                    for n = 1:numHSubImages
                        yidx = ((n-1)*subImageSide+1):(n*subImageSide);

                        subMasks = masks(xidx,yidx,:);
                        numSubmMasks = size(subMasks,3);
                        subMasksScore = zeros(numSubmMasks,1);
                        for i = 1:numSubmMasks
                            subMasksScore(i) = sum(subMasks(:,:,i),"all");
                        end
                        [maxScore,maxScoreIdx] = max(subMasksScore);

                        if (maxScore >= 0.5)
                            terrain = roiClasses(maxScoreIdx);
                            terrainIdx = find(strcmp(terrainClasses,terrain));
                            classMap(m,n) = terrainIdx;
                        else
                            app.ImageNoClasses(xidx,yidx,2:3) = 0;
                        end
                    end
                end

                % Check if there is any sub-image without a class
                numSubImagesWithoutClass = sum((classMap == 0),"all");
                if (numSubImagesWithoutClass > 0)
                    resizedImage = imresize(app.ImageNoClasses,1/app.ResizeFactor);
                else
                    resizedImage = imresize(app.Image,1/app.ResizeFactor);
                    name = sprintf('Maps//%s.tiff',app.ImageName(1:end-4));
                    imwrite(classMap,name)
                end 
                imageHandle = imhandles(app.UIFigure);
                set(imageHandle,'CData',resizedImage)
            end
        end

        % Button pushed function: CityButton
        function CityButtonPushed(app, event)
            terrainClass = "city";
            drawROI(app,terrainClass);
        end

        % Button pushed function: ForestButton
        function ForestButtonPushed(app, event)
            terrainClass = "forest";
            drawROI(app,terrainClass);
        end

        % Button pushed function: MountainButton
        function MountainButtonPushed(app, event)
            terrainClass = "mountain";
            drawROI(app,terrainClass);
        end

        % Button pushed function: DesertButton
        function DesertButtonPushed(app, event)
            terrainClass = "desert";
            drawROI(app,terrainClass);
        end

        % Button pushed function: WaterButton
        function WaterButtonPushed(app, event)
            terrainClass = "water";
            drawROI(app,terrainClass);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Image';
            app.CityButton = uibutton(app.UIFigure, 'push');
            app.CityButton.ButtonPushedFcn = createCallbackFcn(app, @CityButtonPushed, true);
            app.CityButton.Text = 'City';
            app.ForestButton = uibutton(app.UIFigure, 'push');
            app.ForestButton.ButtonPushedFcn = createCallbackFcn(app, @ForestButtonPushed, true);
            app.ForestButton.Text = 'Forest';
            app.MountainButton = uibutton(app.UIFigure, 'push');
            app.MountainButton.ButtonPushedFcn = createCallbackFcn(app, @MountainButtonPushed, true);
            app.MountainButton.Text = 'Mountain';
            app.DesertButton = uibutton(app.UIFigure, 'push');
            app.DesertButton.ButtonPushedFcn = createCallbackFcn(app, @DesertButtonPushed, true);
            app.DesertButton.Text = 'Desert';
            app.WaterButton = uibutton(app.UIFigure, 'push');
            app.WaterButton.ButtonPushedFcn = createCallbackFcn(app, @WaterButtonPushed, true);
            app.WaterButton.Text = 'Water';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end